classdef NN_VSC_Pakr_208971 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FcintensitySlider       matlab.ui.control.Slider
        FcintensitySliderLabel  matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        NeuronsSlider           matlab.ui.control.Slider
        NeuronsSliderLabel      matlab.ui.control.Label
        TrainButton             matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n               % number of 
        xs              % x input (test)
        xst             %
        ys              % y input (test)
        yst             %
        Xs              % x mesh
        Xst             %
        Ys              % y mesh
        Yst             % 
        Zs              % z langermann
        Zst             %
        XY              %
        xys             %
        net             %
        epochs          %
        trained_net     %
        neurons         % number of neurons
        outputs         %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes,"Langermann fuction");
            title(app.UIAxes2,"Approximation of Langermann fuction");
            app.neurons = 50;
            app.NeuronsSlider.Value = 50;
            app.n=50;
            app.FcintensitySlider.Value = 50;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)

            StartButtonPushed(app, event)
            %app.n=200;
            app.xs = linspace(0,10, app.n);
            app.ys = linspace(0,10, app.n);

            app.XY = rand(app.n, 2)*2-1;
            [app.Xst,app.Yst] = meshgrid(app.XY(:,1),app.XY(:,2));

            app.net = fitnet(app.neurons,"trainscg");
            app.xys = [app.xs; app.ys]';
            app.trained_net = train(app.net,app.XY.',app.xys');
            app.net.trainParam.epochs = 100;
            
            app.outputs = (app.trained_net(app.XY'))';
            [app.Xst,app.Yst] = meshgrid(app.outputs(:,1),app.outputs(:,2));
            f = @(xx) Langermann_fce(xx);
            for i=1:app.n
                for j=1:app.n
                    app.Zst(i,j) = f([app.Xst(i,j),app.Yst(i,j)]);
                end
            end
            trained_fig = meshc(app.UIAxes2,app.Xst,app.Yst,app.Zst);
            trained_fig(2).EdgeColor = 'w';                                 % Hides the ugly bottom

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.xs = linspace(0,10, app.n);
            app.ys = linspace(0,10, app.n);
            [app.Xs,app.Ys] = meshgrid(app.xs,app.ys);
            f = @(xx) Langermann_fce(xx);
            for i=1:app.n
                for j=1:app.n
                    app.Zs(i,j) = f([app.Xs(i,j),app.Ys(i,j)]);
                end
            end
            untrained_fig = meshc(app.UIAxes,app.Xs, app.Ys, app.Zs);
            untrained_fig(2).EdgeColor = 'w';                               % Hides the ugly bottom
            title(app.UIAxes,"Langermann fuction");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,"reset")
            cla(app.UIAxes2,"reset")
            startupFcn(app)
        end

        % Value changed function: NeuronsSlider
        function NeuronsSliderValueChanged(app, event)
            app.neurons = app.NeuronsSlider.Value;
            app.neurons = (round(app.neurons/3))*3;
        end

        % Value changed function: FcintensitySlider
        function FcintensitySliderValueChanged(app, event)
            app.n = app.FcintensitySlider.Value;
            app.n = (round(app.n/3))*3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.NeuronsSliderLabel = uilabel(app.UIFigure);
            app.NeuronsSliderLabel.Text = 'Neurons';
            app.NeuronsSlider = uislider(app.UIFigure);
            app.NeuronsSlider.ValueChangedFcn = createCallbackFcn(app, @NeuronsSliderValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FcintensitySliderLabel = uilabel(app.UIFigure);
            app.FcintensitySliderLabel.Text = 'Fc intensity';
            app.FcintensitySlider = uislider(app.UIFigure);
            app.FcintensitySlider.ValueChangedFcn = createCallbackFcn(app, @FcintensitySliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NN_VSC_Pakr_208971

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end